classdef chanInfoCSV_v8 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ElectrodeParameterstoCSVGUIUIFigure  matlab.ui.Figure
        ExportButton_mw      matlab.ui.control.Button
        UITable_mw           matlab.ui.control.Table
        MicroCSVLabel        matlab.ui.control.Label
        CheckBox             matlab.ui.control.CheckBox
        Image3               matlab.ui.control.Image
        Image2               matlab.ui.control.Image
        Image                matlab.ui.control.Image
        ExportButton         matlab.ui.control.Button
        MacroCSVLabel        matlab.ui.control.Label
        UITable              matlab.ui.control.Table
        EnterButton          matlab.ui.control.Button
        userInputElectrodes  matlab.ui.control.NumericEditField
        TotalnumberofmacroelectrodesLabel  matlab.ui.control.Label
        GUIabel              matlab.ui.control.Label
    end

    
    properties (Access = private)
        chanKey % chanKey is accessible to entire app now, call with app.chanKey
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Search current directiory for xls file and load as a table
            app.chanKey = readtable('Channel name key.xlsx');            
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            numElectrodes = app.userInputElectrodes.Value;
            % Create empty array with 'numElectrodes' rows
            numRows = numElectrodes;
            
            % Create cell array with column names
            csvColumnNames = {'depEnum', 'startChan', 'stopChan',...
                'shortN', 'longN', 'hemi', 'numChan'};
            
            % Calculate column number from column names and create cell matrix
            dataMatrix = cell(numRows, length(csvColumnNames));
            
            % Fill in 1st column of table with number of electrodes
            dataTable = array2table(dataMatrix, 'VariableNames',...
                csvColumnNames);

            % Convert array to accept strings 
            dataTable.depEnum = num2cell((1:numElectrodes)'); 
            
            % Populate UI table
            app.UITable.Data = dataTable;
            
            % Empty cell array for each of the 3 user input variables 
            longN = cell(numElectrodes, 1);
            hemi = cell(numElectrodes, 1);
            numChan = cell(numElectrodes, 1);
            
            % Convert info in xlsx file to cell array
            chanKey_cell = table2cell(app.chanKey);
                        
            % Loop through numElectrodes to fill in values for each electrode
                for i = 1:numElectrodes
                    
                    % User input for longN, hemi, and numChan columns
                    prompt = {['Hemisphere: E',num2str(i)],...
                        ['Long name of brain region: E',num2str(i)],...
                        ['Number of channels: E',num2str(i)]};
                    dlgtitle = 'electrodeInfo';
                    dims = [1 35]; % dimensions of dlg box
                    definput = {'L','anterior hippocampus', '16'};
                    answer = inputdlg(prompt,dlgtitle,dims,definput);           
              
                        % Fill empty cells with dlg input
                        hemi{i} = upper(answer{1});
                        longN{i} = lower(answer{2});
                        numChan{i} = answer{3};
                   
                    % Calculate startChan column 
                    if i == 1
                        app.UITable.Data{i,2} = {1};
                    else
                        app.UITable.Data{i,2} = ...
                            {app.UITable.Data{i-1,2}{1}...
                            + str2double(numChan{i-1})};
                    end
                    
                    % Calculate stopChan column
                    app.UITable.Data{i,3} = ...
                        {(app.UITable.Data{i,2}{1}...
                        + str2double(numChan{i}))-1};
                    
                    % Determine shortN from longN input
                        % Location of longN in logical array of chanKey_cell
                        TF = matches(chanKey_cell,longN{i});
                    
                        % Row and column coordinates of longN
                        [row,col] = find(TF==1);

                        % shortN is either 1 or 2 column(s) to the left
                            % in chanKey_cell, depending on hemisphere
                        if hemi{i} == 'R'
                        shortN_loc = chanKey_cell(row,(col-1));
                        else 
                        shortN_loc = chanKey_cell(row,(col-2));    
                        end

                        % Add to GUI table
                        app.UITable.Data(i,4) = {shortN_loc};
                end 
                
              % Add to GUI table
              app.UITable.Data(:,5) = longN;
              app.UITable.Data(:,6) = hemi;
              app.UITable.Data(:,7) = numChan;


%%%%%%% WORK IN PROGRESS %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 

% To do:
% - Create better spell check for long name
%   - Option to re-enter long name if misspelled
% - Create option to add new long name to excel file 
% - Disable components if not in use

%               % Catch spelling errors in longN input
%               if row == 0
%                 % Dialog box
%                 prompt = {['No longN found. Please enter either "misspelling" or "new longN" for E',num2str(i)]};
%                 dlgtitle = 'longN error';
%                 dims = [1 35]; %dimensions of dlg box
%                 definput = {'misspelling'};
%                 answer = inputdlg(prompt,dlgtitle,dims,definput);          
%               end
                           

%     errordlg...
%      ('Long name of one or more brain regions entered were not found. Please check for spelling errors and re-enter'...
%      ,'Spelling error');


%             % Loop through all answers to see if there is a match or not
%                 % If error, is this a misspelling or do you need to create new?
%             chanKey; % column long names and column for the short names
%             longNameVec;
%             shortNameOutput = cell(length(longNameVec,1));
%             misMatchCk = false(length(longNameVec,1));
%             for i = 1:length(longNameVec)
%             indeX = matches(longNameVec{i},chanKey.LongName); %[0 1 0 0 0 0]
%                 if any(indeX)       
%                     shortName = chanKey.shortName(indeX);      
%                     shortNameOutput{i} = shortName;      
%                 else       
%                  misMatchCk(i) = true;       
%                 end   
%             end
% 
%             if any(misMatchCk)
%    
%     % Loop through the mismatches and ask the User for clarification
%     misMatchID = find(misMatchCk);
%     misMatchTruth = cell(sum(misMatchCk,1));
%     for mi = 1:sum(misMatchCk)
%     
%         longNameI = longNameVec{misMatchID(mi)};
%         
%         % uiconfirm
%         answer = uiconfirm(figAxes, 'Mispell?','Misspelling', 'NewBrain Area');
%         
%         misMatchTruth{mi} = answer;
%     end
%     
%     % Loop through the misMatchTruth answers to determine what to do
%     for ti = 1:sum(misMatchCk)
%         
%         truthI = misMatchTruth{ti};
%         
%         if matches(truthI,'Misspelling')
%             % Use inputdlg brain area
%             
%             % Store that answer/output in the correct index of the original
%             % longNameVec - overwriting the incorrect spelling
%             
%         elseif matches(truthI,'NewBrain Area')
%            
%             % Use inputdlg to get the novel shortname
%             
%         end
%         
%         
%         
%     end
%     
% end
%                 
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
        % Export as CSV file and save 
        tempDir = uigetdir('Select Save Location');
        cd(tempDir);
        writetable(app.UITable.Data,'macroChanInfo.csv');
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            
            % Create empty array with 8 rows
            numRows_mw = 8;
            
            % Create cell array with column names
            csvColumnNames_mw = {'depEnum', 'mwInd', 'chanNum',...
                'shortN', 'longN', 'hemi'};
            
            % Calculate column number from column names and create cell matrix
            dataMatrix_mw = cell(numRows_mw, length(csvColumnNames_mw));
            
            % Fill in 1st column of table with number of electrodes
            dataTable_mw = array2table(dataMatrix_mw, ...
                'VariableNames', csvColumnNames_mw);
            
            % Populate UI table
            app.UITable_mw.Data = dataTable_mw;

            % Dialog box
            prompt = {'Which depth electrode contains the microwire?'};
            dlgtitle = 'Microwire';
            dims = [1 35]; % dimensions of dlg box
            definput = {'1'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            
            % Fill empty cells with dlg input
            app.UITable_mw.Data(:,1) = answer;
            
            % Convert info in macro table to cell array
            macroTable = app.UITable.Data;
            macroTable_cell = table2cell(macroTable);
           
            % Populate micro GUI table 
            for m = 1:numRows_mw
                % mwInd column
                app.UITable_mw.Data{m,2} = {m};
                
                % chanNum column
                if m == 1
                   app.UITable_mw.Data{m,3} = {257};
                else
                   app.UITable_mw.Data{m,3} = ...
                       {(app.UITable_mw.Data{m-1,3}{1})+1};
                end
                
                %shortN, longN, and hemi columns
                    % Column of macroTable_cell with depEnums
                    depEnum_loc = string(macroTable_cell(:,1));

                    % Find depEnum that matches 'answer'
                    TF_mw = matches(depEnum_loc,answer);

                    % Row and column coordinates of 'answer'
                    [row,~] = find(TF_mw==1);

                    % Find shortN, longN, and hemi of mw depEnum
                    shortN_loc = macroTable_cell(row,4);
                    longN_loc = macroTable_cell(row,5);
                    hemi_loc = macroTable_cell(row,6);

                    % Add to micro GUI table
                    app.UITable_mw.Data(m,4) = shortN_loc;
                    app.UITable_mw.Data(m,5) = longN_loc;
                    app.UITable_mw.Data(m,6) = hemi_loc;

            end
            
        end

        % Button pushed function: ExportButton_mw
        function ExportButton_mwPushed(app, event)
        % Export as CSV file and save 
        tempDir = uigetdir('Select Save Location');
        cd(tempDir);
        writetable(app.UITable_mw.Data,'microChanInfo.csv'); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ElectrodeParameterstoCSVGUIUIFigure = uifigure('Visible', 'off');
            app.ElectrodeParameterstoCSVGUIUIFigure.Name = 'Electrode Parameters to CSV GUI';
            app.GUIabel = uilabel(app.ElectrodeParameterstoCSVGUIUIFigure);
            app.GUIabel.Text = 'Electrode Parameters to CSV';
            app.TotalnumberofmacroelectrodesLabel = uilabel(app.ElectrodeParameterstoCSVGUIUIFigure);
            app.TotalnumberofmacroelectrodesLabel.Text = 'Total number of macro electrodes';
            app.userInputElectrodes = uieditfield(app.ElectrodeParameterstoCSVGUIUIFigure, 'numeric');
            app.EnterButton = uibutton(app.ElectrodeParameterstoCSVGUIUIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.UITable = uitable(app.ElectrodeParameterstoCSVGUIUIFigure);
            app.UITable.ColumnName = {'depEnum'; 'startChan'; 'stopChan'; 'shortN'; 'longN'; 'hemi'; 'numChan'};
            app.UITable.RowName = {};
            app.MacroCSVLabel = uilabel(app.ElectrodeParameterstoCSVGUIUIFigure);
            app.MacroCSVLabel.Text = 'Macro CSV';
            app.ExportButton = uibutton(app.ElectrodeParameterstoCSVGUIUIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.Image = uiimage(app.ElectrodeParameterstoCSVGUIUIFigure);
            app.Image.ImageSource = 'nlx.jpeg';
            app.Image2 = uiimage(app.ElectrodeParameterstoCSVGUIUIFigure);
            app.Image2.ImageSource = 'csv3.jpg';
            app.Image3 = uiimage(app.ElectrodeParameterstoCSVGUIUIFigure);
            app.Image3.ImageSource = 'arrowword.jpeg.png';
            app.CheckBox = uicheckbox(app.ElectrodeParameterstoCSVGUIUIFigure);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = 'Micro electrode data';
            app.MicroCSVLabel = uilabel(app.ElectrodeParameterstoCSVGUIUIFigure);
            app.MicroCSVLabel.Text = 'Micro CSV';
            app.UITable_mw = uitable(app.ElectrodeParameterstoCSVGUIUIFigure);
            app.UITable_mw.ColumnName = {'depEnum'; 'mwInd'; 'chanNum'; 'shortN'; 'longN'; 'hemi'};
            app.UITable_mw.RowName = {};
            app.ExportButton_mw = uibutton(app.ElectrodeParameterstoCSVGUIUIFigure, 'push');
            app.ExportButton_mw.ButtonPushedFcn = createCallbackFcn(app, @ExportButton_mwPushed, true);
            app.ExportButton_mw.Text = 'Export';
            % Show the figure after all components are created
            app.ElectrodeParameterstoCSVGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = chanInfoCSV_v8

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ElectrodeParameterstoCSVGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ElectrodeParameterstoCSVGUIUIFigure)
        end
    end
end