classdef chanInfoCSV_v5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ElectrodeParameterstoCSVGUIUIFigure  matlab.ui.Figure
        ExportButton_mw      matlab.ui.control.Button
        UITable_mw           matlab.ui.control.Table
        MicroCSVLabel        matlab.ui.control.Label
        CheckBox             matlab.ui.control.CheckBox
        Image3               matlab.ui.control.Image
        Image2               matlab.ui.control.Image
        Image                matlab.ui.control.Image
        ExportButton         matlab.ui.control.Button
        MacroCSVLabel        matlab.ui.control.Label
        UITable              matlab.ui.control.Table
        EnterButton          matlab.ui.control.Button
        userInputElectrodes  matlab.ui.control.NumericEditField
        TotalnumberofmacroelectrodesLabel  matlab.ui.control.Label
        GUIabel              matlab.ui.control.Label
    end

    
    properties (Access = private)
        chanKey % chanKey is accessible to entire app now, call with app.chanKey
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %search current directiory for xls file and load as a table
            app.chanKey = readtable('Channel name key.xlsx');            
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            numElectrodes = app.userInputElectrodes.Value;
            % Create empty array with 'numElectrodes' rows
            numRows = numElectrodes;
            
            % Create cell array with column names
            csvColumnNames = {'depEnum', 'startChan', 'stopChan',...
                'shortN', 'longN', 'hemi', 'numChan'};
            
            % Calculate column number from column names and create cell matrix
            dataMatrix = cell(numRows, length(csvColumnNames));
            
            % Fill in 1st column of table with number of electrodes
            dataTable = array2table(dataMatrix, 'VariableNames',...
                csvColumnNames);

            % Convert array to accept strings and not only numerical inputs
            dataTable.depEnum= num2cell((1:numElectrodes)'); 
            
            % Populate UI table
            app.UITable.Data = dataTable;
            
            % Empty cell array for each of the 3 variables to fill in
            longN = cell(numElectrodes, 1);
            hemi = cell(numElectrodes, 1);
            numChan = cell(numElectrodes, 1);
                        
            % Loop through numElectrodes to fill in longN, hemi, and numChan for each electrode
                for i = 1:numElectrodes
                
                    prompt = {['Hemisphere: E',num2str(i)],...
                        ['Long name of brain region: E',num2str(i)],...
                        ['Number of channels: E',num2str(i)]};
                    dlgtitle = 'electrodeInfo';
                    dims = [1 35]; %dimensions of dlg box
                    definput = {'L','anterior hippocampus', '16'};
                    answer = inputdlg(prompt,dlgtitle,dims,definput);           
              
                    % Fill empty cells with dlg input
                    hemi{i} = answer{1};
                    longN{i} = answer{2};
                    numChan{i} = answer{3};
                end 
                
              % Add to GUI table
              app.UITable.Data(:,5) = longN;
              app.UITable.Data(:,6) = hemi;
              app.UITable.Data(:,7) = numChan;
            
            % Calculate startChan column 
            for k = 1:numElectrodes
                if k == 1
                    app.UITable.Data{k,2} = {1};
                else
                  app.UITable.Data{k,2} = {app.UITable.Data{k-1,2}{1}...
                      + str2double(numChan{k-1})};
                end
            end
                
            % Calculate stopChan column
            for kk = 1:numElectrodes
                app.UITable.Data{kk,3} = {(app.UITable.Data{kk,2}{1}...
                    + str2double(numChan{kk}))-1};
            end
   
            % Determine shortN from longN input
            for j = 1:numElectrodes
                switch longN{j}
                    case 'orbital frontal'
                        if hemi{j} == 'L'
                            app.UITable.Data(j,4) = {'LOF'};
                        else
                            app.UITable.Data(j,4) = {'ROF'};
                        end
                    case 'anterior frontal'
                        if hemi{j} == 'L'
                            app.UITable.Data(j,4) = {'LAF'};
                        else
                            app.UITable.Data(j,4) = {'RAF'};
                        end
                    case 'mid frontal'
                        if hemi{j} == 'L'
                            app.UITable.Data(j,4) = {'LMF'};
                        else
                            app.UITable.Data(j,4) = {'RMF'};
                        end 
                    case 'posterior frontal a'
                        if hemi{j} == 'L'
                            app.UITable.Data(j,4) = {'LPFa'};
                        else
                            app.UITable.Data(j,4) = {'RPFa'};
                        end 
                    case 'posterior frontal b'
                        if hemi{j} == 'L'
                            app.UITable.Data(j,4) = {'LPFb'};
                        else
                            app.UITable.Data(j,4) = {'RPFb'};
                        end 
                    case 'sensory motor area'
                        if hemi{j} == 'L'
                            app.UITable.Data(j,4) = {'LSMA'};
                        else
                            app.UITable.Data(j,4) = {'RSMA'};
                        end 
                    case 'anterior hippocampus'
                        if hemi{j} == 'L'
                            app.UITable.Data(j,4) = {'LAH'};
                        else
                            app.UITable.Data(j,4) = {'RAH'};
                        end 
                    case 'posterior hippocampus'
                        if hemi{j} == 'L'
                            app.UITable.Data(j,4) = {'LPH'};
                        else
                            app.UITable.Data(j,4) = {'RPH'};
                        end 
                    case 'amygdala'
                        if hemi{j} == 'L'
                            app.UITable.Data(j,4) = {'LAMY'};
                        else
                            app.UITable.Data(j,4) = {'RAMY'};
                        end  
                    case 'anterior insula'
                        if hemi{j} == 'L'
                            app.UITable.Data(j,4) = {'LAI'};
                        else
                            app.UITable.Data(j,4) = {'RAI'};
                        end                         
                    case 'mid insula'
                        if hemi{j} == 'L'
                            app.UITable.Data(j,4) = {'LMI'};
                        else
                            app.UITable.Data(j,4) = {'RMI'};
                        end                                                 
                    case 'posterior insula'
                        if hemi{j} == 'L'
                            app.UITable.Data(j,4) = {'LPI'};
                        else
                            app.UITable.Data(j,4) = {'RPI'};
                        end                         
                    case 'anterior cingulate'
                        if hemi{j} == 'L'
                            app.UITable.Data(j,4) = {'LAC'};
                        else
                            app.UITable.Data(j,4) = {'RAC'};
                        end                         
                    case 'posterior cingulate'
                        if hemi{j} == 'L'
                            app.UITable.Data(j,4) = {'LPC'};
                        else
                            app.UITable.Data(j,4) = {'RPC'};
                        end                         
                    case 'temporal pole'
                        if hemi{j} == 'L'
                            app.UITable.Data(j,4) = {'LPOL'};
                        else
                            app.UITable.Data(j,4) = {'RPOL'};
                        end                         
                    case 'basal temporal'
                        if hemi{j} == 'L'
                            app.UITable.Data(j,4) = {'LBAS'};
                        else
                            app.UITable.Data(j,4) = {'RBAS'};
                        end                         
                    case 'mid parietal'
                        if hemi{j} == 'L'
                            app.UITable.Data(j,4) = {'LPFb'};
                        else
                            app.UITable.Data(j,4) = {'RPFb'};
                        end                                        
                    otherwise
                        errordlg...
                            ('Long name of one or more brain regions entered were not found. Please check for spelling errors and re-enter'...
                            ,'Spelling error');
                end
            end
                       
%%%%%%% WORK IN PROGRESS %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 

% To do:
% - Create better spell check for long name
%   - Option to re-enter long name if misspelled
% - Create option to add new long name to excel file 
% - Disable components if not in use

            
%             % Loop through all answers to see if there is a match or not
%                 % If error, is this a misspelling or do you need to create new?
%             chanKey; % column long names and column for the short names
%             longNameVec;
%             shortNameOutput = cell(length(longNameVec,1));
%             misMatchCk = false(length(longNameVec,1));
%             for i = 1:length(longNameVec)
%             indeX = matches(longNameVec{i},chanKey.LongName); %[0 1 0 0 0 0]
%                 if any(indeX)       
%                     shortName = chanKey.shortName(indeX);      
%                     shortNameOutput{i} = shortName;      
%                 else       
%                  misMatchCk(i) = true;       
%                 end   
%             end
% 
%             if any(misMatchCk)
%    
%     % Loop through the mismatches and ask the User for clarification
%     misMatchID = find(misMatchCk);
%     misMatchTruth = cell(sum(misMatchCk,1));
%     for mi = 1:sum(misMatchCk)
%     
%         longNameI = longNameVec{misMatchID(mi)};
%         
%         % uiconfirm
%         answer = uiconfirm(figAxes, 'Mispell?','Misspelling', 'NewBrain Area');
%         
%         misMatchTruth{mi} = answer;
%     end
%     
%     % Loop through the misMatchTruth answers to determine what to do
%     for ti = 1:sum(misMatchCk)
%         
%         truthI = misMatchTruth{ti};
%         
%         if matches(truthI,'Misspelling')
%             % Use inputdlg brain area
%             
%             % Store that answer/output in the correct index of the original
%             % longNameVec - overwriting the incorrect spelling
%             
%         elseif matches(truthI,'NewBrain Area')
%            
%             % Use inputdlg to get the novel shortname
%             
%         end
%         
%         
%         
%     end
%     
% end
%                 
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
        % Export as CSV file and save 
        tempDir=uigetdir('Select Save Location');
        cd(tempDir);
        writetable(app.UITable.Data,'macroChanInfo.csv');
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            
            % Create empty array with 8 rows
            numRows_mw = 8;
            
            % Create cell array with column names
            csvColumnNames_mw = {'depEnum', 'mwInd', 'chanNum',...
                'shortN', 'longN', 'hemi'};
            
            % Calculate column number from column names and create cell matrix
            dataMatrix_mw = cell(numRows_mw, length(csvColumnNames_mw));
            
            % Fill in 1st column of table with number of electrodes
            dataTable_mw = array2table(dataMatrix_mw, 'VariableNames',...
                csvColumnNames_mw);

            % Convert array to accept strings and not only numerical inputs
            %dataTable_mw.depEnum= num2cell((value)'); 
            
            % Populate UI table
            app.UITable_mw.Data = dataTable_mw;

            % Dialog box
            prompt = {['Which depth electrode contains the microwire?']};
            dlgtitle = 'Microwire';
            dims = [1 35]; %dimensions of dlg box
            definput = {'1'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);

            % Fill empty cells with dlg input
            app.UITable_mw.Data(:,1) = answer;
            
            % Populate mwInd and chanNum columns
            for m = 1:numRows_mw
                app.UITable_mw.Data{m,2} = {m};
                if m == 1
                   app.UITable_mw.Data{m,3} = {257};
                else
                    app.UITable_mw.Data{m,3} = {(app.UITable_mw.Data{m-1,3}{1})+1};
                end
            end

 %%%%%%% WORK IN PROGRESS %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%           
            
            % Populate shortN, longN, and hemi based on macroCSV table
            answer_new = str2double(answer);
            r = app.UITable.Data(:,1);
            q_temp2=r(1,:);
            q_temp = {table2cell(q_temp2)};
            q = q_temp{1};
            
                %q= answer(r);
                app.UITable_mw.Data{m,4} = q;
                %app.UITable_mw.Data{m,5} = 
                %app.UITable_mw.Data{m,6} = 
            
            
        end

        % Button pushed function: ExportButton_mw
        function ExportButton_mwPushed(app, event)
        % Export as CSV file and save 
        tempDir=uigetdir('Select Save Location');
        cd(tempDir);
        writetable(app.UITable.Data,'microChanInfo.csv'); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ElectrodeParameterstoCSVGUIUIFigure = uifigure('Visible', 'off');
            app.ElectrodeParameterstoCSVGUIUIFigure.Name = 'Electrode Parameters to CSV GUI';
            app.GUIabel = uilabel(app.ElectrodeParameterstoCSVGUIUIFigure);
            app.GUIabel.Text = 'Electrode Parameters to CSV';
            app.TotalnumberofmacroelectrodesLabel = uilabel(app.ElectrodeParameterstoCSVGUIUIFigure);
            app.TotalnumberofmacroelectrodesLabel.Text = 'Total number of macro electrodes';
            app.userInputElectrodes = uieditfield(app.ElectrodeParameterstoCSVGUIUIFigure, 'numeric');
            app.EnterButton = uibutton(app.ElectrodeParameterstoCSVGUIUIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.UITable = uitable(app.ElectrodeParameterstoCSVGUIUIFigure);
            app.UITable.ColumnName = {'depEnum'; 'startChan'; 'stopChan'; 'shortN'; 'longN'; 'hemi'; 'numChan'};
            app.UITable.RowName = {};
            app.MacroCSVLabel = uilabel(app.ElectrodeParameterstoCSVGUIUIFigure);
            app.MacroCSVLabel.Text = 'Macro CSV';
            app.ExportButton = uibutton(app.ElectrodeParameterstoCSVGUIUIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.Image = uiimage(app.ElectrodeParameterstoCSVGUIUIFigure);
            app.Image.ImageSource = 'nlx.jpeg';
            app.Image2 = uiimage(app.ElectrodeParameterstoCSVGUIUIFigure);
            app.Image2.ImageSource = 'csv3.jpg';
            app.Image3 = uiimage(app.ElectrodeParameterstoCSVGUIUIFigure);
            app.Image3.ImageSource = 'arrowword.jpeg.png';
            app.CheckBox = uicheckbox(app.ElectrodeParameterstoCSVGUIUIFigure);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = 'Micro electrode data';
            app.MicroCSVLabel = uilabel(app.ElectrodeParameterstoCSVGUIUIFigure);
            app.MicroCSVLabel.Text = 'Micro CSV';
            app.UITable_mw = uitable(app.ElectrodeParameterstoCSVGUIUIFigure);
            app.UITable_mw.ColumnName = {'depEnum'; 'mwInd'; 'chanNum'; 'shortN'; 'longN'; 'hemi'};
            app.UITable_mw.RowName = {};
            app.ExportButton_mw = uibutton(app.ElectrodeParameterstoCSVGUIUIFigure, 'push');
            app.ExportButton_mw.ButtonPushedFcn = createCallbackFcn(app, @ExportButton_mwPushed, true);
            app.ExportButton_mw.Text = 'Export';
            % Show the figure after all components are created
            app.ElectrodeParameterstoCSVGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = chanInfoCSV_v5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ElectrodeParameterstoCSVGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ElectrodeParameterstoCSVGUIUIFigure)
        end
    end
end